classdef AblineQuickCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IRAbsorptionVisualizerUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavefiguredataasMenu            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Step1Tab                        matlab.ui.container.Tab
        PFselect                        matlab.ui.control.DropDown
        All0willcalculatealllinesinHITRANfileLabel  matlab.ui.control.Label
        MolarmassgmolEditField          matlab.ui.control.NumericEditField
        MolarmassgmolEditFieldLabel     matlab.ui.control.Label
        dataLoadInd_2                   matlab.ui.control.Label
        MyPartFundataButton             matlab.ui.control.Button
        FrequencyUnitDropDown           matlab.ui.control.DropDown
        FrequencyUnitDropDownLabel      matlab.ui.control.Label
        Frequencylimit2EditField        matlab.ui.control.NumericEditField
        Frequencylimit2EditFieldLabel   matlab.ui.control.Label
        Frequencylimit1EditField        matlab.ui.control.NumericEditField
        Frequencylimit1EditFieldLabel   matlab.ui.control.Label
        dataLoadInd                     matlab.ui.control.Label
        ImportHITRANdataButton          matlab.ui.control.Button
        Step2Tab                        matlab.ui.container.Tab
        HoldonSwitch                    matlab.ui.control.Switch
        HoldonSwitchLabel               matlab.ui.control.Label
        ResolutionSlider                matlab.ui.control.Slider
        ResolutionSliderLabel           matlab.ui.control.Label
        UnitinYDropDown                 matlab.ui.control.DropDown
        UnitinYDropDownLabel            matlab.ui.control.Label
        PressureatmEditField            matlab.ui.control.NumericEditField
        PressureatmEditFieldLabel       matlab.ui.control.Label
        TemperatureKEditField           matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel      matlab.ui.control.Label
        GasmolefractionEditField        matlab.ui.control.NumericEditField
        GasmolefractionEditFieldLabel   matlab.ui.control.Label
        OpticallengthcmEditField        matlab.ui.control.NumericEditField
        OpticallengthcmEditFieldLabel   matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        UnitinXDropDown                 matlab.ui.control.DropDown
        UnitinXDropDownLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        hitranstate = 0;
        pfstate = 0;
        filename
        path
        pffilename
        pfpath% Description
        opX;
        opY;
        condition;
        labelall = {}; % Description
        previousT=[] % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportHITRANdataButton
        function ImportHITRANdataButtonPushed(app, event)
            [file,pat] = uigetfile('*.*');
            if file ~= 0
                app.hitranstate = 1;
                app.filename = file;
                app.path = pat;
                app.dataLoadInd.Text = ['File loaded@ ',file];
            else
                uialert(app.IRAbsorptionVisualizerUIFigure,'Operation Abort','Info','Icon',"error")
            end
        end

        % Button pushed function: MyPartFundataButton
        function MyPartFundataButtonPushed(app, event)
            [file,pat] = uigetfile('*.*');
            if file ~= 0
                app.pfstate = 1;
                app.pffilename = file;
                app.pfpath = pat;
                app.dataLoadInd_2.Text = ['File loaded@ ',file];
                app.MolarmassgmolEditField.Enable = 1;
            else
                uialert(app.IRAbsorptionVisualizerUIFigure,'Operation Abort','Info','Icon',"error")
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

            if app.hitranstate == 0
                uialert(app.IRAbsorptionVisualizerUIFigure,'No HITRAN file found','Error','Icon',"error")
                return
            elseif app.pfstate == 0
                %use in built data
                %                 uialert(app.IRAbsorptionVisualizerUIFigure,'No partition function file found','Error','Icon',"error")
                %                 return
            end
            app.GenerateButton.Enable = 'off';
            pause(0.1)
            
            T = app.TemperatureKEditField.Value;
            
            H2O = app.GasmolefractionEditField.Value;
            
            P = app.PressureatmEditField.Value; %oneatm
            
            L = app.OpticallengthcmEditField.Value;
            
            app.condition = [num2str(T),'K_',num2str(H2O),'c_',num2str(P),'atm_',num2str(L),'cm'];

            
            %import or load parition function
            if app.pfstate == 1
                opts = detectImportOptions([app.pfpath,app.pffilename]);
                Q_h2o=readtable([app.pfpath,app.pffilename],opts);
                %define molecule ID
                M_h2o = app.MolarmassgmolEditField.Value;
            elseif app.PFselect.Value == "H2O"
                opts = detectImportOptions('Partfun_H16OH.txt');
                Q_h2o=readtable('Partfun_H16OH.txt',opts);
                M_h2o = 18;
            elseif app.PFselect.Value == "C2H4"
                opts = detectImportOptions('Partfun_H212C12CH2.txt');
                Q_h2o=readtable('Partfun_H212C12CH2.txt',opts);
                M_h2o = 12*2+4;
            elseif app.PFselect.Value == "C2H2"
                opts = detectImportOptions('Partfun_H12C12CH.txt');
                Q_h2o=readtable('Partfun_H12C12CH.txt',opts);
                M_h2o = 12*2+2;
            elseif app.PFselect.Value == "CO2"
                opts = detectImportOptions('Partfun_16O12C16O.txt');
                Q_h2o=readtable('Partfun_16O12C16O.txt',opts);
                M_h2o = 12+16*2;
            elseif app.PFselect.Value == "N2O"
                opts = detectImportOptions('Partfun_14N14N16O.txt');
                Q_h2o=readtable('Partfun_14N14N16O.txt',opts);
                M_h2o = 14*2+16;
            end
            Q_h2o=Q_h2o.Q_T_; %Q|T=296 = Q(296);


            %import HITRAN data
            h2o_hitran = readtable([app.path,app.filename]);
            h = height(h2o_hitran);
            

            % Set wavenumber range
            if app.Frequencylimit1EditField.Value == 0 && app.Frequencylimit2EditField.Value == 0
                
                nu_start = h2o_hitran.nu(1);
                nu_end = h2o_hitran.nu(end);
                
                
            elseif app.FrequencyUnitDropDown.Value == "nm" %nm
                v1 = app.Frequencylimit1EditField.Value;
                v2 = app.Frequencylimit2EditField.Value;
                v1 = 1e7/v1;
                v2 = 1e7/v2;
                if v1>v2
                    nu_start = v2;
                    nu_end = v1;
                else
                    nu_start = v1;
                    nu_end = v2;
                end
                
            elseif app.FrequencyUnitDropDown.Value == "cm-1" %cm-1
                v1 = app.Frequencylimit1EditField.Value;
                v2 = app.Frequencylimit2EditField.Value;
                if v1>v2
                    nu_start = v2;
                    nu_end = v1;
                else
                    nu_start = v1;
                    nu_end = v2;
                end
                
            elseif app.FrequencyUnitDropDown.Value == "µm"%µm
                v1 = 1e3*app.Frequencylimit1EditField.Value;
                v2 = 1e3*app.Frequencylimit2EditField.Value;
                v1 = 1e7/v1;
                v2 = 1e7/v2;
                if v1>v2
                    nu_start = v2;
                    nu_end = v1;
                else
                    nu_start = v1;
                    nu_end = v2;
                end
                
                
            end
            nu_range = nu_end - nu_start;
            zoom = app.ResolutionSlider.Value;
            nu_range_zoom = nu_range * zoom;
            startpoint = find(abs(h2o_hitran.nu-nu_start)<0.1);
            endpoint = find(abs(h2o_hitran.nu-nu_end)<0.1);
            
            if isempty(startpoint)
                startline = 1;
            elseif startpoint(1) - 10 >= 1
                startline = startpoint(1) - 10;
            else
                startline = 1;
            end
            
            if isempty(endpoint)
                endline = h;
            elseif endpoint(1) + 10 <= h
                endline = endpoint(1) + 10;
            else
                endline = h;
            end
            
            if app.Frequencylimit1EditField.Value == 0 && app.Frequencylimit2EditField.Value == 0
                startline = 1;
                endline = h;
            end
            %H2O
            nu0 = h2o_hitran.nu;
            sw = h2o_hitran.sw;
            gamma_air = h2o_hitran.gamma_air;
            gamma_self = h2o_hitran.gamma_self;
            elower = h2o_hitran.elower;
            n_air = h2o_hitran.n_air;
            delta_air = h2o_hitran.delta_air;
            
            species_alpha =[];
            v = [];
            
            for nu = 1:nu_range_zoom
                
                alpha = 0;
                
                for num_nu0=startline:1:endline
                    
                    nu_0 = nu / zoom + nu_start;
                    
                    alpha_temp = abline(nu_0,nu0(num_nu0),sw(num_nu0),...
                        gamma_air(num_nu0),gamma_self(num_nu0),...
                        elower(num_nu0),n_air(num_nu0),M_h2o,...
                        H2O,Q_h2o(296),Q_h2o(round(T)),T,P,L,delta_air(num_nu0));
                    alpha = alpha + alpha_temp;
                    
                end
                v(nu) = nu_0;
                species_alpha(nu) = alpha;
                
            end
                app.opX = v;
                app.opY = species_alpha;
                
                
                if app.HoldonSwitch.Value == "On"
                    app.labelall = [app.labelall [num2str(T),' K']]; 
                    hold(app.UIAxes,"on");
                    
                else
                    hold(app.UIAxes,"off");
                    legend(app.UIAxes,"off");
                    app.labelall = {};
                    app.labelall = [app.labelall [num2str(T),' K']];

                end
            if app.UnitinXDropDown.Value == "nm" && app.UnitinYDropDown.Value == "alpha(nu)"%nm,cm-1%alphanu,ab
                plot(app.UIAxes,1e7./v,species_alpha,'LineWidth',2);
                xlabel(app.UIAxes,'Wavelength /nm')
                ylabel(app.UIAxes,'alpha(nu)')
                xlim(app.UIAxes,[min(1e7./v),max(1e7./v)]);
            end
            
            if app.UnitinXDropDown.Value == "cm-1" && app.UnitinYDropDown.Value == "alpha(nu)"%nm,cm-1%alphanu,ab
                plot(app.UIAxes,v,species_alpha,'LineWidth',2);
                xlabel(app.UIAxes,'Wavenumber /cm^{-1}')
                ylabel(app.UIAxes,'alpha(nu)')
                xlim(app.UIAxes,[min(v),max(v)]);
            end
            
            if app.UnitinXDropDown.Value == "nm" && app.UnitinYDropDown.Value == "Absorption"%nm,cm-1%alphanu,ab
                plot(app.UIAxes,1e7./v,(1-exp(-species_alpha)).*100,'LineWidth',2);
                xlabel(app.UIAxes,'Wavelength /nm')
                ylabel(app.UIAxes,'Absorption /%')
                xlim(app.UIAxes,[min(1e7./v),max(1e7./v)]);
            end
            
            if app.UnitinXDropDown.Value == "cm-1" && app.UnitinYDropDown.Value == "Absorption"%alphanu,ab
                plot(app.UIAxes,v,(1-exp(-species_alpha)).*100,'LineWidth',2);
                xlabel(app.UIAxes,'Wavenumber /cm^{-1}')
                ylabel(app.UIAxes,'Absorption /%')
                xlim(app.UIAxes,[min(v),max(v)]);
            end
            if app.HoldonSwitch.Value == "On"
            legend(app.UIAxes,app.labelall);
            end
            app.GenerateButton.Enable = 'on';
        end

        % Menu selected function: SavefiguredataasMenu
        function SavefiguredataasMenuSelected(app, event)
            if ~isnan(app.opX)
                savePATH = uigetdir('Choose the Destination');
                if savePATH ~= 0
                    tosave = table(app.opX',app.opY');
                    tosave.Properties.VariableNames = {'nu','alpha_nu'};
                    writetable(tosave,[savePATH,'/nv_alpha_nv_at_',app.condition,'.csv']);
                    uialert(app.IRAbsorptionVisualizerUIFigure,['File saved as nv_alpha_nv_at_',app.condition,'.csv. located at:',savePATH],'Info','Icon',"success")
                else
                    uialert(app.IRAbsorptionVisualizerUIFigure,"Please select the output folder.",'Info','Icon',"warning")
                end
            else
                uialert(app.IRAbsorptionVisualizerUIFigure,"No figure exist",'Info','Icon',"warning")
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            uialert(app.IRAbsorptionVisualizerUIFigure,"Check README.pdf distributed with this APP",'Info','Icon',"warning")
        end

        % Value changed function: PFselect
        function PFselectValueChanged(app, event)
            value = app.PFselect.Value;
            app.pfstate = 0;
            app.dataLoadInd_2.Text = 'No data loaded, use in built data';
            app.MolarmassgmolEditField.Enable = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IRAbsorptionVisualizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {376, 376};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IRAbsorptionVisualizerUIFigure = uifigure('Visible', 'off');
            app.IRAbsorptionVisualizerUIFigure.Name = 'IR Absorption Visualizer';
            app.IRAbsorptionVisualizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.IRAbsorptionVisualizerUIFigure);
            app.FileMenu.Text = 'File';
            app.SavefiguredataasMenu = uimenu(app.FileMenu);
            app.SavefiguredataasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefiguredataasMenuSelected, true);
            app.SavefiguredataasMenu.Text = 'Save figure data as...';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.IRAbsorptionVisualizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.ImportHITRANdataButton = uibutton(app.Step1Tab, 'push');
            app.ImportHITRANdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHITRANdataButtonPushed, true);
            app.ImportHITRANdataButton.Text = 'Import HITRAN data';
            app.dataLoadInd = uilabel(app.Step1Tab);
            app.dataLoadInd.Text = 'No data loaded';
            app.Frequencylimit1EditFieldLabel = uilabel(app.Step1Tab);
            app.Frequencylimit1EditFieldLabel.Text = 'Frequency limit 1';
            app.Frequencylimit1EditField = uieditfield(app.Step1Tab, 'numeric');
            app.Frequencylimit1EditField.ValueDisplayFormat = '%11.6g';
            app.Frequencylimit2EditFieldLabel = uilabel(app.Step1Tab);
            app.Frequencylimit2EditFieldLabel.Text = 'Frequency limit 2';
            app.Frequencylimit2EditField = uieditfield(app.Step1Tab, 'numeric');
            app.Frequencylimit2EditField.ValueDisplayFormat = '%11.6g';
            app.FrequencyUnitDropDownLabel = uilabel(app.Step1Tab);
            app.FrequencyUnitDropDownLabel.Text = 'Frequency Unit';
            app.FrequencyUnitDropDown = uidropdown(app.Step1Tab);
            app.FrequencyUnitDropDown.Items = {'nm', 'cm-1', 'µm'};
            app.FrequencyUnitDropDown.Value = 'nm';
            app.MyPartFundataButton = uibutton(app.Step1Tab, 'push');
            app.MyPartFundataButton.ButtonPushedFcn = createCallbackFcn(app, @MyPartFundataButtonPushed, true);
            app.MyPartFundataButton.Text = 'My PartFun data';
            app.dataLoadInd_2 = uilabel(app.Step1Tab);
            app.dataLoadInd_2.Text = 'No data loaded, use in built data';
            app.MolarmassgmolEditFieldLabel = uilabel(app.Step1Tab);
            app.MolarmassgmolEditFieldLabel.Text = 'Molar mass (g/mol)';
            app.MolarmassgmolEditField = uieditfield(app.Step1Tab, 'numeric');
            app.MolarmassgmolEditField.Enable = 'off';
            app.MolarmassgmolEditField.Value = 44;
            app.All0willcalculatealllinesinHITRANfileLabel = uilabel(app.Step1Tab);
            app.All0willcalculatealllinesinHITRANfileLabel.Text = {'All 0 will calculate all lines '; 'in HITRAN file'; 'Too big HITRAN file '; 'may cause APP no-response'};
            app.PFselect = uidropdown(app.Step1Tab);
            app.PFselect.Items = {'H2O', 'C2H4', 'CO2', 'N2O', 'C2H2'};
            app.PFselect.ValueChangedFcn = createCallbackFcn(app, @PFselectValueChanged, true);
            app.PFselect.Value = 'H2O';
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step 2';
            app.UnitinXDropDownLabel = uilabel(app.Step2Tab);
            app.UnitinXDropDownLabel.Text = 'Unit in X';
            app.UnitinXDropDown = uidropdown(app.Step2Tab);
            app.UnitinXDropDown.Items = {'nm', 'cm-1'};
            app.UnitinXDropDown.Value = 'nm';
            app.GenerateButton = uibutton(app.Step2Tab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.OpticallengthcmEditFieldLabel = uilabel(app.Step2Tab);
            app.OpticallengthcmEditFieldLabel.Text = 'Optical length /cm';
            app.OpticallengthcmEditField = uieditfield(app.Step2Tab, 'numeric');
            app.OpticallengthcmEditField.Value = 10;
            app.GasmolefractionEditFieldLabel = uilabel(app.Step2Tab);
            app.GasmolefractionEditFieldLabel.Text = 'Gas mole fraction';
            app.GasmolefractionEditField = uieditfield(app.Step2Tab, 'numeric');
            app.GasmolefractionEditField.ValueDisplayFormat = '%11.6g';
            app.GasmolefractionEditField.Value = 0.1;
            app.TemperatureKEditFieldLabel = uilabel(app.Step2Tab);
            app.TemperatureKEditFieldLabel.Text = 'Temperature /K';
            app.TemperatureKEditField = uieditfield(app.Step2Tab, 'numeric');
            app.TemperatureKEditField.Value = 298;
            app.PressureatmEditFieldLabel = uilabel(app.Step2Tab);
            app.PressureatmEditFieldLabel.Text = 'Pressure /atm';
            app.PressureatmEditField = uieditfield(app.Step2Tab, 'numeric');
            app.PressureatmEditField.Value = 1;
            app.UnitinYDropDownLabel = uilabel(app.Step2Tab);
            app.UnitinYDropDownLabel.Text = 'Unit in Y';
            app.UnitinYDropDown = uidropdown(app.Step2Tab);
            app.UnitinYDropDown.Items = {'alpha(nu)', 'Absorption'};
            app.UnitinYDropDown.Value = 'Absorption';
            app.ResolutionSliderLabel = uilabel(app.Step2Tab);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.Step2Tab);
            app.ResolutionSlider.Limits = [10 1000];
            app.ResolutionSlider.MajorTicks = [10 1000];
            app.ResolutionSlider.MajorTickLabels = {'Rough', 'Smooth'};
            app.ResolutionSlider.Value = 100;
            app.HoldonSwitchLabel = uilabel(app.Step2Tab);
            app.HoldonSwitchLabel.Text = '"Hold on"';
            app.HoldonSwitch = uiswitch(app.Step2Tab, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.IRAbsorptionVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AblineQuickCheck

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IRAbsorptionVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IRAbsorptionVisualizerUIFigure)
        end
    end
end